classdef TrialAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BatchTrialAnalyzerUIFigure      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        AnalyzeButton                   matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ExportStackedPlot               matlab.ui.container.Tab
        HistogramTab                    matlab.ui.container.Tab
        DataDistributionHistogramPanel  matlab.ui.container.GridLayout
        CorrelationTable                matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        PValuesHeatMapTab               matlab.ui.container.Tab
        HeatMapPvalsContainer           matlab.ui.container.Panel
        SingleOutputAnalyzerTab         matlab.ui.container.Tab
        singleOutputCorrTableTitle      matlab.ui.control.Label
        singleOutputCorrTable           matlab.ui.control.Table
        singleOutputCorrTree            matlab.ui.container.CheckBoxTree
        SelectOutputLabel               matlab.ui.control.Label
        CoefficientsHeatMapTab          matlab.ui.container.Tab
        HeatMapCoefsContainer           matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        trialTable % Description
        CorrelationResults % Description
        corCoefHeatMapAxes % Description
        corCoefHeatMapHandle % Description
        corPvalsHeatMapAxes % Description
        corPvalsHeatMapHandle % Description
        gplotMatrixHandle % Description
        prevCheckedNode % Description
    end
    
    methods (Access = private)
        
        % Function to populate the tree structure
function populateTree(app, data,treeObj)
    columnNames = data.Properties.VariableNames;
    rootNodeMap = containers.Map();
    
    for i = 1:numel(columnNames)
        columnName = columnNames{i};
        nodes = strsplit(columnName, '__');
        
        parentNode = treeObj;
        parentKey = '';
        
        for j = 1:numel(nodes)
            nodeName = nodes{j};
            currentKey = [parentKey, '__', nodeName];
            
            if ~isKey(rootNodeMap, currentKey)
                node = uitreenode(parentNode);
                node.Text = nodeName;
                rootNodeMap(currentKey) = node;
            else
                node = rootNodeMap(currentKey);
            end
            
            parentNode = node;
            parentKey = currentKey;
        end
    end
end

    end
    
    methods (Access = public)


function getCheckedNodesWithParents(app,tree)
    checkedNodes = tree.CheckedNodes;
    for i = 1:numel(checkedNodes)
        node = checkedNodes(i);
        nodePath = getNodePath(node);
        disp(nodePath);
    end
end

function path = getNodePath(app, node)
    path = node.Text;
    currentNode = node;
    while ~isempty(currentNode.Parent) && isa(currentNode.Parent, 'matlab.ui.container.TreeNode')
        currentNode = currentNode.Parent;
        path = [currentNode.Text '__' path];
    end
end


    end %method
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LoadDataButtonPushed(app);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            file = 'trialLogs/trialTable.mat';
            if isfile(file)
                load(file, 'trialTable');
                app.trialTable = trialTable;
                app.populateTree(trialTable,app.Tree);
                app.populateTree(trialTable,app.singleOutputCorrTree);

                
            else
                uialert(app.BatchTrialAnalyzerUIFigure, 'File not found', 'Error');
            end
        
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)


    % Get checked nodes and their full column names
   % In the callback section, add the following functions
checkedNodes = app.Tree.CheckedNodes;
selectedColumns = {};

for i = 1:numel(checkedNodes)
    node = checkedNodes(i);
    
    % Check if the node is a leaf node (no children)
    if isempty(node.Children)
        column = app.getNodePath(node);
        selectedColumns{end+1} = column;
    end
end


trialTable = app.trialTable;
firstColumnName = trialTable.Properties.VariableNames{1}; % Get the first column name
tableData = trialTable(:, [{firstColumnName}, selectedColumns]);
app.UITable.Data = tableData;

% Update the table's ColumnName property with the new column names
app.UITable.ColumnName = [{firstColumnName}, selectedColumns];

% % Perform correlation analysis and store the results
 numericData = table2array(app.trialTable(:, selectedColumns)); % Exclude the first column as it might not be numeric
 [app.CorrelationResults.coefs, app.CorrelationResults.pvals, app.CorrelationResults.RL, app.CorrelationResults.RU]  = corrcoef(numericData); % Calculate the correlation matrix
coefs = app.CorrelationResults.coefs;
pvals = app.CorrelationResults.pvals;
% Replace underscores with hyphens in variable names
selectedColumnsNames = strrep(selectedColumns, '_', '-');
% In the callback section, after calculating the correlation matrix
 stackedplot(tableData(:, selectedColumns), 'Parent', app.ExportStackedPlot); % Exclude the first column (trialID)
grid on;


% Delete previous heatmap if it exists
if isfield(app, 'corCoefHeatMapHandle') && isvalid(app.corCoefHeatMapHandle)
    delete(app.corCoefHeatMapHandle);
end


% Create a heatmap using the coefs matrix
app.corCoefHeatMapHandle = heatmap(app.HeatMapCoefsContainer, coefs, ...
    'XData', selectedColumnsNames, 'YData', selectedColumnsNames, ...
    'ColorMap', jet, 'CellLabelColor', 'none');


% Add a title to the heatmap
app.corCoefHeatMapHandle.Title = 'Correlation Coefficients  Heatmap';
%-------------------------

% Delete previous heatmap if it exists
if isfield(app, 'corPvalsHeatMapHandle') && isvalid(app.corPvalsHeatMapHandle)
    delete(app.corCoefHeatMapHandle);
end


% Create a heatmap using the coefs matrix
app.corPvalsHeatMapHandle = heatmap(app.HeatMapPvalsContainer, pvals, ...
    'XData', selectedColumnsNames, 'YData', selectedColumnsNames, ...
    'ColorMap', jet, 'CellLabelColor', 'none');


% Add a title to the heatmap
app.corPvalsHeatMapHandle.Title = 'Correlation P-Values  Heatmap';

%-----------------------
% Clear the DataDistributionHistogramPanel
delete(app.DataDistributionHistogramPanel.Children);

% Create a GridLayout inside the DataDistributionHistogramPanel
gridLayout = uigridlayout(app.DataDistributionHistogramPanel);

% Get the number of selected columns
numColumns = numel(selectedColumns);

% Calculate the number of rows and columns for the GridLayout arrangement
numRows = ceil(sqrt(numColumns));
numCols = ceil(numColumns / numRows);

% Configure GridLayout dimensions
gridLayout.RowHeight = repmat({'1x'}, 1, numRows);
gridLayout.ColumnWidth = repmat({'1x'}, 1, numCols);

% Get the data from the selected columns
columnData = app.trialTable{:, selectedColumns};

% Create histograms for each selected column
for i = 1:numColumns
    ax = uiaxes(gridLayout);
    histogram(ax, columnData(:, i));
    title(ax, selectedColumns{i}, 'Interpreter', 'none');
    % Calculate the row and column index for the current histogram
    row = ceil(i / numCols);
    col = mod(i - 1, numCols) + 1;
    % Set the histogram's position in the GridLayout
    ax.Layout.Row = row;
    ax.Layout.Column = col;
end


        end

        % Callback function: singleOutputCorrTree
        function singleOutputCorrTreeCheckedNodesChanged(app, event)
    

    if isempty(event.CheckedNodes)
        return;
    end

    % Get the checked node
    checkedNode = event.CheckedNodes(end);
    
    % Check if the node is a non-children node and uncheck it
    if ~isempty(checkedNode(end).Children)
        uialert(app.BatchTrialAnalyzerUIFigure,"Select Single Output",'Error');
        return;
    end

    % Uncheck the previously checked node if it exists
    if isfield(app, 'prevCheckedNode') && app.prevCheckedNode ~= checkedNode
        app.prevCheckedNode.Checked = false;
    end
    
    
    % Store the current checked node as the previous checked node
    app.prevCheckedNode = checkedNode;
    

    % Get the selected columns from app.Tree
    checkedNodes = app.Tree.CheckedNodes;
    selectedColumns = {};
    for i = 1:numel(checkedNodes)
        node = checkedNodes(i);
% Check if the node is a leaf node (no children)
    if isempty(node.Children)
        column = app.getNodePath(node);
        selectedColumns{end+1} = column;
    end

    end
    
    % Perform correlation analysis between the checked node in the
    % singleOutputCorrTree and the selected columns from app.Tree
    singleOutputColumnName = app.getNodePath(checkedNode);
    allColumns = [{singleOutputColumnName}, selectedColumns];
    numericData = app.trialTable{:, allColumns};
    
    [coefs, pvals, RL, RU] = corrcoef(numericData); % Calculate the correlation matrix
    
    % Show the correlation results in the singleOutputCorrTable

    app.singleOutputCorrTable.ColumnName = {'Feature', 'Correlation Coefficient', 'P-Value', 'RL', 'RU'};
    tableData = table(selectedColumns', coefs(2:end, 1), pvals(2:end, 1), RL(2:end, 1), RU(2:end, 1), ...
        'VariableNames', {'Feature', 'Correlation_Coefficient', 'P_Value', 'Lower Band', 'Upper Band'});
    
    % Add a title for the table
    app.singleOutputCorrTableTitle.Text = sprintf('Correlation analysis of %s', singleOutputColumnName);

    % Sort the table by the absolute value of the correlation coefficient
    [~, sortIndex] = sort(abs(tableData.Correlation_Coefficient), 'descend');
    sortedTableData = tableData(sortIndex, :);

    % Update the table data
    app.singleOutputCorrTable.Data = sortedTableData;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BatchTrialAnalyzerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {868, 868};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {295, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BatchTrialAnalyzerUIFigure = uifigure('Visible', 'off');
            app.BatchTrialAnalyzerUIFigure.Name = 'Batch Trial Analyzer';
            app.BatchTrialAnalyzerUIFigure.Icon = fullfile(pathToMLAPP, 'TrialExplorer_icon.png');
            app.BatchTrialAnalyzerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BatchTrialAnalyzerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Tree = uitree(app.LeftPanel, 'checkbox');
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Reload Data';
            app.AnalyzeButton = uibutton(app.LeftPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze Selected Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Trial ID'; ''; ''; ''};
            app.UITable.RowName = {};
            app.TabGroup = uitabgroup(app.RightPanel);
            app.ExportStackedPlot = uitab(app.TabGroup);
            app.ExportStackedPlot.Title = 'Stacked Plot';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.DataDistributionHistogramPanel = uigridlayout(app.HistogramTab);
            app.CorrelationTable = uitab(app.TabGroup);
            app.CorrelationTable.Title = 'Correlation Analysis';
            app.TabGroup2 = uitabgroup(app.CorrelationTable);
            app.CoefficientsHeatMapTab = uitab(app.TabGroup2);
            app.CoefficientsHeatMapTab.Title = 'Coefficients Heat Map';
            app.HeatMapCoefsContainer = uipanel(app.CoefficientsHeatMapTab);
            app.PValuesHeatMapTab = uitab(app.TabGroup2);
            app.PValuesHeatMapTab.Title = 'P-ValuesHeat Map';
            app.HeatMapPvalsContainer = uipanel(app.PValuesHeatMapTab);
            app.SingleOutputAnalyzerTab = uitab(app.TabGroup2);
            app.SingleOutputAnalyzerTab.Title = 'Single Output Analyzer';
            app.SelectOutputLabel = uilabel(app.SingleOutputAnalyzerTab);
            app.SelectOutputLabel.Text = 'Select Output';
            app.singleOutputCorrTree = uitree(app.SingleOutputAnalyzerTab, 'checkbox');
            % Assign Checked Nodes
            app.singleOutputCorrTree.CheckedNodesChangedFcn = createCallbackFcn(app, @singleOutputCorrTreeCheckedNodesChanged, true);
            app.singleOutputCorrTable = uitable(app.SingleOutputAnalyzerTab);
            app.singleOutputCorrTable.ColumnName = {''; ''; ''; ''};
            app.singleOutputCorrTable.ColumnRearrangeable = 'on';
            app.singleOutputCorrTable.RowName = {};
            app.singleOutputCorrTable.ColumnSortable = true;
            app.singleOutputCorrTableTitle = uilabel(app.SingleOutputAnalyzerTab);
            app.singleOutputCorrTableTitle.Text = '';
            % Show the figure after all components are created
            app.BatchTrialAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrialAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BatchTrialAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BatchTrialAnalyzerUIFigure)
        end
    end
end