classdef TrialAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BatchTrialAnalyzerUIFigure      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ExportWorkSpaceButton           matlab.ui.control.Button
        analysistypeButton              matlab.ui.container.ButtonGroup
        TimeframeAnalysisButton         matlab.ui.control.RadioButton
        TrialAnalysisButton             matlab.ui.control.RadioButton
        AnalyzeButton                   matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ExportStackedPlot               matlab.ui.container.Tab
        HistogramTab                    matlab.ui.container.Tab
        DataDistributionHistogramPanel  matlab.ui.container.GridLayout
        CorrelationTable                matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        CoefficientsHeatMapTab          matlab.ui.container.Tab
        HeatMapCoefsContainer           matlab.ui.container.Panel
        PValuesHeatMapTab               matlab.ui.container.Tab
        HeatMapPvalsContainer           matlab.ui.container.Panel
        CohendHeatMapTab                matlab.ui.container.Tab
        HeatMapDContainer               matlab.ui.container.Panel
        CorrelationPlotTab              matlab.ui.container.Tab
        CorrelationPlotPanelAxis        matlab.ui.control.UIAxes
        SingleOutputAnalyzerTab         matlab.ui.container.Tab
        singleOutputCorrTableTitle      matlab.ui.control.Label
        singleOutputCorrTable           matlab.ui.control.Table
        singleOutputCorrTree            matlab.ui.container.CheckBoxTree
        SelectOutputLabel               matlab.ui.control.Label
        AutoAnalysisTab                 matlab.ui.container.Tab
        InputsLabel                     matlab.ui.control.Label
        OutputsLabel                    matlab.ui.control.Label
        OutputDataTree                  matlab.ui.container.CheckBoxTree
        OutputSurveyDataNode            matlab.ui.container.TreeNode
        OutputIrisDataNode              matlab.ui.container.TreeNode
        OutputCATDataNode               matlab.ui.container.TreeNode
        OutputExceedanceDataNode        matlab.ui.container.TreeNode
        InputDataTree                   matlab.ui.container.CheckBoxTree
        InputSurveyDataNode             matlab.ui.container.TreeNode
        InputIrisDataNode               matlab.ui.container.TreeNode
        InputCATDataNode                matlab.ui.container.TreeNode
        InputExceedanceDataNode         matlab.ui.container.TreeNode
        autoAnalyzerReportTable         matlab.ui.control.Table
        AutoAnalyzeButton               matlab.ui.control.Button
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        dataTable;
        CorrelationResults % Description
        corCoefHeatMapAxes % Description
        corCoefHeatMapHandle % Description
        corPvalsHeatMapAxes % Description
        corPvalsHeatMapHandle % Description
        gplotMatrixHandle % Description
        prevCheckedNode % Description
        correlationPlotHandle % Description
        corDHeatMapHandle % Description
        selectedColumnsNames % Description
        selectedColumns
    end
    
    methods (Access = private)
        
        % Function to populate the tree structure
function populateTree(app, data,treeObj)
%clear old data
a = treeObj.Children;
a.delete;

    columnNames = data.Properties.VariableNames;
    rootNodeMap = containers.Map();
    
    for i = 1:numel(columnNames)
        columnName = columnNames{i};
        nodes = strsplit(columnName, '__');
        
        parentNode = treeObj;
        parentKey = '';
        
        for j = 1:numel(nodes)
            nodeName = nodes{j};
            currentKey = [parentKey, '__', nodeName];
            
            if ~isKey(rootNodeMap, currentKey)
                node = uitreenode(parentNode);
                node.Text = nodeName;
                rootNodeMap(currentKey) = node;
            else
                node = rootNodeMap(currentKey);
            end
            
            parentNode = node;
            parentKey = currentKey;
        end
    end
end

    end %method
    
    methods (Access = public)


function getCheckedNodesWithParents(app,tree)
    checkedNodes = tree.CheckedNodes;
    for i = 1:numel(checkedNodes)
        node = checkedNodes(i);
        nodePath = getNodePath(node);
        disp(nodePath);
    end
end

function path = getNodePath(app, node)
    path = node.Text;
    currentNode = node;
    while ~isempty(currentNode.Parent) && isa(currentNode.Parent, 'matlab.ui.container.TreeNode')
        currentNode = currentNode.Parent;
        path = [currentNode.Text '__' path];
    end
end


        function updatedTable = forceRealValues(app,dataTable)
    updatedTable = dataTable;
    
    for row = 1:size(dataTable, 1)
        for col = 1:size(dataTable, 2)
            cellValue = dataTable{row, col};
            
            if isnumeric(cellValue) && ~isreal(cellValue)
                updatedTable{row, col} = real(cellValue);
            end
        end
    end
end


       function selected_node = get_selected_node(app,tree)
    nodes = tree.Children;
    for i = 1:numel(nodes)
        if nodes(i).Checked
            selected_node = nodes(i).Text;
            break;
        end
    end
end

        
      function column_names = getColumnNamesByNode(app, node_text)
    switch node_text.Text
        case 'Survey Data'
            prefix = 'trial__';
        case 'Iris Data'
            prefix = 'irisResult__';
        case 'CAT Data'
            prefix = 'avgFlightCAT__';
        case 'Exceedance Data'
            prefix = 'exceedance__';
    end
    column_names = app.dataTable.Properties.VariableNames(startsWith(app.dataTable.Properties.VariableNames, prefix));
    
    % Ignore specified columns
    columnsToIgnore = {'trial__beginPVT', 'trial__endPVT'};
    column_names = setdiff(column_names, columnsToIgnore);
end


        function customDataTip(~,event_obj)
    % Get the position of the data point
    pos = event_obj.Position;
    
    % Get the plot's UserData, which contains the variable names
    varNames = event_obj.Target.Parent.UserData.varNames;
    
    % Set the DataTip text
    event_obj.DataTip.DataTipComponent.Tooltip = sprintf('Var1: %s\nVar2: %s\nCorr: %.2f', varNames{pos(1)}, varNames{pos(2)}, pos(3));
end

    end %method
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        LoadDataButtonPushed(app);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
                selectedAnslysisType = app.analysistypeButton.SelectedObject;

            if(selectedAnslysisType == app.TimeframeAnalysisButton)
                disp("tf");
            file = 'trialLogs/timeFrameTable.mat';

                if isfile(file)
                    load(file, 'timeFrameTable');
                    app.dataTable = timeFrameTable;
                    
                else
                    uialert(app.BatchTrialAnalyzerUIFigure, 'File not found', 'Error');
                end

            elseif(selectedAnslysisType == app.TrialAnalysisButton )
                                disp("trial");

            file = 'trialLogs/trialTable.mat';
                if isfile(file)
                    load(file, 'trialTable');
                    app.dataTable = trialTable;
                 
    
                else
                    uialert(app.BatchTrialAnalyzerUIFigure, 'File not found', 'Error');
                end
            end

           app.dataTable = app.forceRealValues(app.dataTable);
            app.populateTree(app.dataTable,app.Tree);
            app.populateTree(app.dataTable,app.singleOutputCorrTree);


        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)


    % Get checked nodes and their full column names
   % In the callback section, add the following functions
checkedNodes = app.Tree.CheckedNodes;
app.selectedColumns = {};

for i = 1:numel(checkedNodes)
    node = checkedNodes(i);
    
    % Check if the node is a leaf node (no children)
    if isempty(node.Children)
        column = app.getNodePath(node);
        app.selectedColumns{end+1} = column;
    end
end


allColumnNames = app.dataTable.Properties.VariableNames;

% Check if 'info__timeframeID' and 'info__trialID' columns exist in the dataTable
hasTimeframeID = ismember('info__timeFrameID', allColumnNames);
hasTrialID = ismember('info__trialID', allColumnNames);

% Create a new array to store the desired column names
newColumnNames = {};

% Add 'info__timeframeID' and 'info__trialID' columns to the newColumnNames array if they exist
if hasTimeframeID
    newColumnNames = [newColumnNames, 'timeFrameID'];
end
if hasTrialID
    %newColumnNames = [newColumnNames, 'trialID'];
end

% Add the first column name and app.selectedColumns to the newColumnNames array
newColumnNames = [newColumnNames, allColumnNames{1}, app.selectedColumns];

% Extract the specified columns from dataTable
tableData = app.dataTable(:, newColumnNames);

% Update the UITable with the new data and column names
app.UITable.Data = tableData;
app.UITable.ColumnName = newColumnNames;


% % Perform correlation analysis and store the results
 numericData = table2array(app.dataTable(:, app.selectedColumns)); % Exclude the first column as it might not be numeric
 [app.CorrelationResults.coefs, app.CorrelationResults.pvals, app.CorrelationResults.RL, app.CorrelationResults.RU]  = corrcoef(numericData); % Calculate the correlation matrix
coefs = app.CorrelationResults.coefs;
pvals = app.CorrelationResults.pvals;
% Replace underscores with hyphens in variable names
app.selectedColumnsNames = strrep(app.selectedColumns, '_', '-');
assignin('base', 'correlationNumericTable', app.dataTable(:, app.selectedColumns));
assignin('base', 'CorrelationColumnsNames', app.selectedColumnsNames);
assignin('base', 'correlationNumericData', numericData);
assignin('base', 'coefs', coefs);
assignin('base', 'pvals', pvals);

% In the callback section, after calculating the correlation matrix
 stackedplot(tableData(:, app.selectedColumns), 'Parent', app.ExportStackedPlot); % Exclude the first column (trialID)


% Delete previous heatmap if it exists
if isfield(app, 'corCoefHeatMapHandle') && isvalid(app.corCoefHeatMapHandle)
    delete(app.corCoefHeatMapHandle);
end


% Create a heatmap using the coefs matrix
app.corCoefHeatMapHandle = heatmap(app.HeatMapCoefsContainer, coefs, ...
    'XData', app.selectedColumnsNames, 'YData', app.selectedColumnsNames, ...
    'ColorMap', jet, 'CellLabelColor', 'none');


% Add a title to the heatmap
app.corCoefHeatMapHandle.Title = 'Correlation Coefficients  Heatmap';
%-------------------------

% Delete previous heatmap if it exists
if isfield(app, 'corPvalsHeatMapHandle') && isvalid(app.corPvalsHeatMapHandle)
    delete(app.corCoefHeatMapHandle);
end


% Create a heatmap using the coefs matrix
app.corPvalsHeatMapHandle = heatmap(app.HeatMapPvalsContainer, pvals, ...
    'XData', app.selectedColumnsNames, 'YData', app.selectedColumnsNames, ...
    'ColorMap', jet, 'CellLabelColor', 'none');


% Add a title to the heatmap
app.corPvalsHeatMapHandle.Title = 'Correlation P-Values  Heatmap';
%--------------------------
% Calculate Cohen's d for correlation coefficients
d = (coefs .* sqrt(size(numericData, 1) - 2)) ./ sqrt(1 - coefs.^2);

% Assign Cohen's d to the base workspace
assignin('base', 'd', d);

% Delete previous heatmap if it exists
if isfield(app, 'corDHeatMapHandle') && isvalid(app.corDHeatMapHandle)
    delete(app.corDHeatMapHandle);
end

% Create a heatmap using the Cohen's d matrix
app.corDHeatMapHandle = heatmap(app.HeatMapDContainer, d, ...
    'XData', app.selectedColumnsNames, 'YData', app.selectedColumnsNames, ...
    'ColorMap', jet, 'CellLabelColor', 'none');

% Add a title to the heatmap
app.corDHeatMapHandle.Title = 'Cohen''s d Heatmap';


%-----------------------
% Clear the DataDistributionHistogramPanel
delete(app.DataDistributionHistogramPanel.Children);

% Create a GridLayout inside the DataDistributionHistogramPanel
gridLayout = uigridlayout(app.DataDistributionHistogramPanel);

% Get the number of selected columns
numColumns = numel(app.selectedColumns);

% Calculate the number of rows and columns for the GridLayout arrangement
numRows = ceil(sqrt(numColumns));
numCols = ceil(numColumns / numRows);

% Configure GridLayout dimensions
gridLayout.RowHeight = repmat({'1x'}, 1, numRows);
gridLayout.ColumnWidth = repmat({'1x'}, 1, numCols);

% Get the data from the selected columns
columnData = app.dataTable{:, app.selectedColumns};

% Create histograms for each selected column
for i = 1:numColumns
    ax = uiaxes(gridLayout);
    histogram(ax, columnData(:, i));
    title(ax, app.selectedColumns{i}, 'Interpreter', 'none');
    % Calculate the row and column index for the current histogram
    row = ceil(i / numCols);
    col = mod(i - 1, numCols) + 1;
    % Set the histogram's position in the GridLayout
    ax.Layout.Row = row;
    ax.Layout.Column = col;
end

%-----------------
% Temporarily disable AutoResizeChildren
app.CorrelationPlotTab.AutoResizeChildren = false;

% Convert the table to a numeric array
dataArray = app.dataTable{:, app.selectedColumns};


% Create the correlation plot with empty variable names
hCorrPlot = corrplot(app.CorrelationPlotPanelAxis, dataArray );

% Get variable names
varNames = app.dataTable.Properties.VariableNames(app.selectedColumns);

% Add variable names as text labels on edge plots
numVars = numel(varNames);
for i = 1:numVars
    for j = 1:numVars
        if i == 1
            % Add labels to the top edge
            pos = [(j-1)/(numVars-1), 1.05];
            hText = text(pos(1), pos(2), varNames{j}, ...
                'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
                'FontSize', 8, 'Units', 'normalized');
            hText.Parent = app.CorrelationPlotPanelAxis;
        elseif j == 1
            % Add labels to the left edge
            pos = [-0.05, (numVars-i)/(numVars-1)];
            hText = text(pos(1), pos(2), varNames{i}, ...
                'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
                'FontSize', 8, 'Units', 'normalized');
            hText.Parent = app.CorrelationPlotPanelAxis;
        end
    end
end

% Re-enable AutoResizeChildren
app.CorrelationPlotTab.AutoResizeChildren = true;


%close(gcf); % Close the current figure

        end

        % Callback function: singleOutputCorrTree
        function singleOutputCorrTreeCheckedNodesChanged(app, event)
    

    if isempty(event.CheckedNodes)
        return;
    end

    % Get the checked node
    checkedNode = event.CheckedNodes(end);
    
    % Check if the node is a non-children node and uncheck it
    if ~isempty(checkedNode(end).Children)
        uialert(app.BatchTrialAnalyzerUIFigure,"Select Single Output",'Error');
        return;
    end

    % Uncheck the previously checked node if it exists
    if isfield(app, 'prevCheckedNode') && app.prevCheckedNode ~= checkedNode
        app.prevCheckedNode.Checked = false;
    end
    
    
    % Store the current checked node as the previous checked node
    app.prevCheckedNode = checkedNode;
    

    % Get the selected columns from app.Tree
    checkedNodes = app.Tree.CheckedNodes;
    app.selectedColumns = {};
    for i = 1:numel(checkedNodes)
        node = checkedNodes(i);
% Check if the node is a leaf node (no children)
    if isempty(node.Children)
        column = app.getNodePath(node);
        app.selectedColumns{end+1} = column;
    end

    end
    
    % Perform correlation analysis between the checked node in the
    % singleOutputCorrTree and the selected columns from app.Tree
    singleOutputColumnName = app.getNodePath(checkedNode);
    allColumns = [{singleOutputColumnName}, app.selectedColumns];
    numericData = app.dataTable{:, allColumns};
    
    [coefs, pvals, RL, RU] = corrcoef(numericData); % Calculate the correlation matrix
    
    % Show the correlation results in the singleOutputCorrTable

    app.singleOutputCorrTable.ColumnName = {'Feature', 'Correlation Coefficient', 'P-Value', 'RL', 'RU'};
    tableData = table(app.selectedColumns', coefs(2:end, 1), pvals(2:end, 1), RL(2:end, 1), RU(2:end, 1), ...
        'VariableNames', {'Feature', 'Correlation_Coefficient', 'P_Value', 'Lower Band', 'Upper Band'});
    
    % Add a title for the table
    app.singleOutputCorrTableTitle.Text = sprintf('Correlation analysis of %s', singleOutputColumnName);

    % Sort the table by the absolute value of the correlation coefficient
    [~, sortIndex] = sort(abs(tableData.Correlation_Coefficient), 'descend');
    sortedTableData = tableData(sortIndex, :);

    % Update the table data
    app.singleOutputCorrTable.Data = sortedTableData;
            
        end

        % Button pushed function: ExportWorkSpaceButton
        function ExportWorkSpaceButtonPushed(app, event)

  % Get the selected columns from app.Tree
    checkedNodes = app.Tree.CheckedNodes;
    app.selectedColumns = {};
    for i = 1:numel(checkedNodes)
        node = checkedNodes(i);
% Check if the node is a leaf node (no children)
    if isempty(node.Children)
        column = app.getNodePath(node);
        app.selectedColumns{end+1} = column;
    end

    end

             numericData = table2array(app.dataTable(:, app.selectedColumns)); % Exclude the first column as it might not be numeric
            assignin('base', 'selectedTable', app.dataTable(:, app.selectedColumns));
assignin('base', 'selectedColumnsNames', app.selectedColumnsNames);
assignin('base', 'selectedData', numericData);
        end

        % Selection changed function: singleOutputCorrTree
        function singleOutputCorrTreeSelectionChanged(app, event)
            selectedNodes = app.singleOutputCorrTree.SelectedNodes;
            
        end

        % Button pushed function: AutoAnalyzeButton
        function AutoAnalyzeButtonPushed(app, event)
           alpha = app.AlphaEditField.Value;
    inputNodes = app.InputDataTree.CheckedNodes;
    outputNodes = app.OutputDataTree.CheckedNodes;

    inputColumnNames = [];
    outputColumnNames = [];

    for i = 1:numel(inputNodes)
        inputColumnNames = [inputColumnNames, app.getColumnNamesByNode( inputNodes(i))];
    end

    for i = 1:numel(outputNodes)
        outputColumnNames = [outputColumnNames, app.getColumnNamesByNode( outputNodes(i))];
    end

    allColumnNames = [inputColumnNames, outputColumnNames];
    [correlations, pValues] = corrcoef(app.dataTable{:, allColumnNames}, 'rows', 'pairwise');
    correlations(isnan(correlations)) = 0;

    % Get input-output correlations from the correlation matrix
    inputOutputCorrelations = correlations(1:numel(inputColumnNames), numel(inputColumnNames)+1:end);
    inputOutputPValues = pValues(1:numel(inputColumnNames), numel(inputColumnNames)+1:end);

      % Prepare table data
    numRows = numel(outputColumnNames) * numel(inputColumnNames);
    tableData = cell(numRows, 4);
    rowIndex = 1;
    for i = 1:numel(outputColumnNames)
        for j = 1:numel(inputColumnNames)
            if i ~= j
                pValue = inputOutputPValues(j, i);
                if pValue <= alpha
                tableData{rowIndex, 1} = outputColumnNames{i};
                tableData{rowIndex, 2} = inputColumnNames{j};
                tableData{rowIndex, 3} = inputOutputCorrelations(j, i);
                tableData{rowIndex, 4} = pValue;
                rowIndex = rowIndex + 1;
                end
            end
        end
    end

    % Remove empty rows
    tableData(cellfun(@isempty, tableData(:, 1)), :) = [];

    % Update the table
    app.autoAnalyzerReportTable.ColumnName = {'Output', 'Input', 'Coefficient', 'p-value'};
    app.autoAnalyzerReportTable.Data = tableData;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BatchTrialAnalyzerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {868, 868};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {295, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BatchTrialAnalyzerUIFigure = uifigure('Visible', 'off');
            app.BatchTrialAnalyzerUIFigure.Name = 'Batch Trial Analyzer';
            app.BatchTrialAnalyzerUIFigure.Icon = fullfile(pathToMLAPP, 'TrialExplorer_icon.png');
            app.BatchTrialAnalyzerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BatchTrialAnalyzerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Tree = uitree(app.LeftPanel, 'checkbox');
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Reload Data';
            app.AnalyzeButton = uibutton(app.LeftPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze Selected Data';
            app.analysistypeButton = uibuttongroup(app.LeftPanel);
            app.TrialAnalysisButton = uiradiobutton(app.analysistypeButton);
            app.TrialAnalysisButton.Text = 'Trial Analysis';
            app.TrialAnalysisButton.Value = true;
            app.TimeframeAnalysisButton = uiradiobutton(app.analysistypeButton);
            app.TimeframeAnalysisButton.Text = 'Timeframe Analysis';
            app.ExportWorkSpaceButton = uibutton(app.LeftPanel, 'push');
            app.ExportWorkSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportWorkSpaceButtonPushed, true);
            app.ExportWorkSpaceButton.Text = 'Export Selected';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Trial ID'; ''; ''; ''};
            app.UITable.RowName = {};
            app.TabGroup = uitabgroup(app.RightPanel);
            app.ExportStackedPlot = uitab(app.TabGroup);
            app.ExportStackedPlot.Title = 'Stacked Plot';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.DataDistributionHistogramPanel = uigridlayout(app.HistogramTab);
            app.CorrelationTable = uitab(app.TabGroup);
            app.CorrelationTable.Title = 'Correlation Analysis';
            app.TabGroup2 = uitabgroup(app.CorrelationTable);
            app.CoefficientsHeatMapTab = uitab(app.TabGroup2);
            app.CoefficientsHeatMapTab.Title = 'Coefficients Heat Map';
            app.HeatMapCoefsContainer = uipanel(app.CoefficientsHeatMapTab);
            app.PValuesHeatMapTab = uitab(app.TabGroup2);
            app.PValuesHeatMapTab.Title = 'P-ValuesHeat Map';
            app.HeatMapPvalsContainer = uipanel(app.PValuesHeatMapTab);
            app.CohendHeatMapTab = uitab(app.TabGroup2);
            app.CohendHeatMapTab.Title = 'Cohen d Heat Map';
            app.HeatMapDContainer = uipanel(app.CohendHeatMapTab);
            app.CorrelationPlotTab = uitab(app.TabGroup2);
            app.CorrelationPlotTab.Title = 'Correlation Plot';
            app.CorrelationPlotPanelAxis = uiaxes(app.CorrelationPlotTab);
            title(app.CorrelationPlotPanelAxis, 'Title')
            xlabel(app.CorrelationPlotPanelAxis, 'X')
            ylabel(app.CorrelationPlotPanelAxis, 'Y')
            zlabel(app.CorrelationPlotPanelAxis, 'Z')
            app.SingleOutputAnalyzerTab = uitab(app.TabGroup2);
            app.SingleOutputAnalyzerTab.Title = 'Single Output Analyzer';
            app.SelectOutputLabel = uilabel(app.SingleOutputAnalyzerTab);
            app.SelectOutputLabel.Text = 'Select Output';
            app.singleOutputCorrTree = uitree(app.SingleOutputAnalyzerTab, 'checkbox');
            app.singleOutputCorrTree.SelectionChangedFcn = createCallbackFcn(app, @singleOutputCorrTreeSelectionChanged, true);
            % Assign Checked Nodes
            app.singleOutputCorrTree.CheckedNodesChangedFcn = createCallbackFcn(app, @singleOutputCorrTreeCheckedNodesChanged, true);
            app.singleOutputCorrTable = uitable(app.SingleOutputAnalyzerTab);
            app.singleOutputCorrTable.ColumnName = {''; ''; ''; ''};
            app.singleOutputCorrTable.ColumnRearrangeable = 'on';
            app.singleOutputCorrTable.RowName = {};
            app.singleOutputCorrTable.ColumnSortable = true;
            app.singleOutputCorrTableTitle = uilabel(app.SingleOutputAnalyzerTab);
            app.singleOutputCorrTableTitle.Text = '';
            app.AutoAnalysisTab = uitab(app.TabGroup2);
            app.AutoAnalysisTab.Title = 'Auto Analysis';
            app.AlphaEditFieldLabel = uilabel(app.AutoAnalysisTab);
            app.AlphaEditFieldLabel.Text = 'Alpha ';
            app.AlphaEditField = uieditfield(app.AutoAnalysisTab, 'numeric');
            app.AlphaEditField.Limits = [0 1];
            app.AlphaEditField.Value = 0.1;
            app.AutoAnalyzeButton = uibutton(app.AutoAnalysisTab, 'push');
            app.AutoAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AutoAnalyzeButtonPushed, true);
            app.AutoAnalyzeButton.Text = 'Analyze';
            app.autoAnalyzerReportTable = uitable(app.AutoAnalysisTab);
            app.autoAnalyzerReportTable.ColumnName = {'Output'; 'Input'; 'Correlation Coefficient'; 'P-Value'};
            app.autoAnalyzerReportTable.ColumnRearrangeable = 'on';
            app.autoAnalyzerReportTable.RowName = {};
            app.autoAnalyzerReportTable.ColumnSortable = true;
            app.autoAnalyzerReportTable.ColumnEditable = true;
            app.InputDataTree = uitree(app.AutoAnalysisTab, 'checkbox');
            app.InputSurveyDataNode = uitreenode(app.InputDataTree);
            app.InputSurveyDataNode.Text = 'Survey Data';
            app.InputIrisDataNode = uitreenode(app.InputDataTree);
            app.InputIrisDataNode.Text = 'Iris Data';
            app.InputCATDataNode = uitreenode(app.InputDataTree);
            app.InputCATDataNode.Text = 'CAT Data';
            app.InputExceedanceDataNode = uitreenode(app.InputDataTree);
            app.InputExceedanceDataNode.Text = 'Exceedance Data';
            app.OutputDataTree = uitree(app.AutoAnalysisTab, 'checkbox');
            app.OutputSurveyDataNode = uitreenode(app.OutputDataTree);
            app.OutputSurveyDataNode.Text = 'Survey Data';
            app.OutputIrisDataNode = uitreenode(app.OutputDataTree);
            app.OutputIrisDataNode.Text = 'Iris Data';
            app.OutputCATDataNode = uitreenode(app.OutputDataTree);
            app.OutputCATDataNode.Text = 'CAT Data';
            app.OutputExceedanceDataNode = uitreenode(app.OutputDataTree);
            app.OutputExceedanceDataNode.Text = 'Exceedance Data';
            app.OutputsLabel = uilabel(app.AutoAnalysisTab);
            app.OutputsLabel.Text = 'Outputs';
            app.InputsLabel = uilabel(app.AutoAnalysisTab);
            app.InputsLabel.Text = 'Inputs';
            % Show the figure after all components are created
            app.BatchTrialAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrialAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BatchTrialAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BatchTrialAnalyzerUIFigure)
        end
    end
end