classdef TrialExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrialExplorerUIFigure       matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        FullFiguresPDFReportButton  matlab.ui.control.Button
        MatFileDropdown             matlab.ui.control.DropDown
        ORLabel                     matlab.ui.control.Label
        SelectLogDropDownLabel      matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
        StructureTreeView           matlab.ui.container.Tree
        RightPanel                  matlab.ui.container.Panel
        DataTable                   matlab.ui.control.Table
        DateLabel                   matlab.ui.control.Label
        NameLabel                   matlab.ui.control.Label
        ValueLabel                  matlab.ui.control.Label
        DataAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        my_struct; % Description
            plotHandle; % Store the plot handle
    end
    
    methods (Access = private)
        
    % Modify the display_struct function
function display_struct(app, parent, struct_obj)
    fields = fieldnames(struct_obj);
    
    for i = 1:numel(fields)
        field_name = fields{i};
        field_value = struct_obj.(field_name);
        field_type = class(field_value);
        
        if isstruct(field_value)
            node = uitreenode(parent, 'Text', [field_name ': (struct)']);
            display_struct(app, node, field_value);
        else
            node = uitreenode(parent, 'Text', [field_name ': (' field_type ')']);
            if isnumeric(field_value) || ischar(field_value)
                node.NodeData = field_value;
            end
        end
    end
end


        function plot_data(app, time, data, field_name)
     if iscell(data)
        cell_data = cell2mat(data);
        app.plotHandle = plot(app.DataAxes, time, cell_data);
    else
        app.plotHandle = plot(app.DataAxes, time, data);
    end
    
    grid(app.DataAxes, 'on');
    
    xlabel(app.DataAxes, 'Time');
    ylabel(app.DataAxes, field_name, 'Interpreter', 'none');
    title(app.DataAxes, sprintf('%s vs Time', strrep(field_name, '_', '-')), 'Interpreter', 'none');
        end
        
        % Private function to update the MatFileDropdown items
% Modify the update_dropdown_items function to include the disabled item
function update_dropdown_items(app)
    default_folder = 'outputLogs';
    mat_files = dir(fullfile(default_folder, '*.mat'));
    mat_file_names = {mat_files.name};
    
    app.MatFileDropdown.Items = ["--select a Log--", mat_file_names];
    app.MatFileDropdown.ItemsData = [0, 1:numel(mat_file_names)];
    app.MatFileDropdown.Value = 0;
end
    end
    
    
    methods (Access = public)
        
        function   updateUI(app,loaded_data)
            struct_name = fieldnames(loaded_data);
    app.my_struct = loaded_data.(struct_name{1});
    
    % Update NameLabel if the 'name' field exists in the struct
    if isfield(app.my_struct.trial, 'name')
        app.NameLabel.Text = sprintf('Name: %s', app.my_struct.trial.name);
    else
        app.NameLabel.Text = 'Name not found';
    end
    
    % Update DateLabel if the 'time' field exists in the struct
    if isfield(app.my_struct.trial, 'time')
        date_str = datestr(app.my_struct.trial.time, 'yyyy-mm-dd HH:MM:SS');
        app.DateLabel.Text = sprintf('Date: %s', date_str);
    else
        app.DateLabel.Text = 'Date not found';
    end
    
    % Clear the existing tree
    app.StructureTreeView.Children.delete;
    
    % Display the struct in the tree view
    display_struct(app, app.StructureTreeView, app.my_struct);
        end
        
        function togglePlotVisibility(app, visible)
    if isempty(app.plotHandle)
        return;
    end
    
    if visible
        app.DataAxes.Visible = 'on';
        app.plotHandle.Visible = 'on';
    else
        app.DataAxes.Visible = 'off';
        app.plotHandle.Visible = 'off';
    end
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_dropdown_items(app);
    app.DataTable.ColumnFormat = {'char', 'char'};
app.DataTable.ColumnEditable = [false, false];


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
          
   default_folder = 'outputLogs';
    [file, path] = uigetfile(fullfile(default_folder, '*.mat'), 'Select a .mat file');
    
    if isequal(file, 0)
        return;
    end
    
    loaded_data = load(fullfile(path, file));
    app.updateUI(loaded_data);

update_dropdown_items(app);
        end

        % Selection changed function: StructureTreeView
        function StructureTreeViewSelectionChanged(app, event)
selected_node = app.StructureTreeView.SelectedNodes;
    field_name = strsplit(selected_node.Text, ':');
    field_name = strtrim(field_name{1});
    
    if isempty(selected_node.NodeData)
        app.ValueLabel.Text = '';
        app.DataTable.Visible = 'off';
         togglePlotVisibility(app, false);
        
    elseif isnumeric(selected_node.NodeData) && isscalar(selected_node.NodeData)
        app.ValueLabel.Text = sprintf('%s: %g', field_name, selected_node.NodeData);
        app.DataTable.Visible = 'off';
        togglePlotVisibility(app, false)

    elseif ischar(selected_node.NodeData)
        app.ValueLabel.Text = sprintf('%s: %s', field_name, selected_node.NodeData);
        app.DataTable.Visible = 'off';
        togglePlotVisibility(app, false)
    else
        data = selected_node.NodeData;
        if (isvector(data) || iscell(data)) && ...
                isfield(app.my_struct, 'trial') && isfield(app.my_struct.trial, 'data') && ...
                isfield(app.my_struct.trial.data, 'time')

            time = app.my_struct.trial.data.time;

            if numel(time) == numel(data)
                plot_data(app, time, data, field_name);
            else
                app.ValueLabel.Text = sprintf('%s: Data and time dimensions do not match', field_name);
            end
        end
         if iscell(data) || ismatrix(data)
            time_col = app.my_struct.trial.data.time;
            if iscell(data)
                table_data = cell(numel(data), 2);
                table_data(:, 1) = num2cell(time_col);
                table_data(:, 2) = data;
            else
                table_data = [time_col, data];
            end
            app.DataTable.ColumnName = {'Time', field_name};
            app.DataTable.Data = table_data;
            app.DataTable.Visible = 'on';
            togglePlotVisibility(app, true);

        else
            app.DataTable.Visible = 'off';
            togglePlotVisibility(app, true);

        end
    end


        end

        % Value changed function: MatFileDropdown
        function MatFileDropdownValueChanged(app, event)
            % Callback function for the MatFileDropdown component
    if app.MatFileDropdown.Value == 0
        return;
    end
     default_folder = 'outputLogs';
    selected_file_index = app.MatFileDropdown.Value+1;
    selected_file = app.MatFileDropdown.Items{selected_file_index};    
    loaded_data = load(fullfile(default_folder, selected_file));
    app.updateUI(loaded_data);


        end

        % Button pushed function: FullFiguresPDFReportButton
        function FullFiguresPDFReportButtonPushed(app, event)
% --- Executes when FullFiguresPDFReportButton is pressed
    % Get the path to the selected log file
    if app.MatFileDropdown.Value == 0
        return;
    end
    selected_file_index = app.MatFileDropdown.Value+1;
    selected_file = app.MatFileDropdown.Items{selected_file_index};

        default_folder = fullfile('logs\firstSeries');

    log_path = fullfile(default_folder, app.my_struct.info.logFolder);
    
    % Get a list of PDF files in the log folder
    pdf_files = dir(fullfile(log_path, '*.pdf'));
    num_files = numel(pdf_files);
    if num_files == 0
        % No PDF file found
        msgbox('No PDF file found in the selected log folder.', 'Error', 'error');
        return;
    elseif num_files > 1
        % More than one PDF file found
        warning_msg = sprintf('%d PDF files found in the selected log folder. Opening the largest file.', num_files);
        warning(warning_msg);
        
        % Sort the files by size (largest first)
        [~, sorted_idx] = sort([pdf_files.bytes], 'descend');
        
        % Select the largest file
        pdf_file = pdf_files(sorted_idx(1));
    else
        % Only one PDF file found
        pdf_file = pdf_files(1);
    end
    
    % Open the selected PDF file
    pdf_path = fullfile(log_path, pdf_file.name);
    if ispc
        system(sprintf('start "" "%s"', pdf_path));
    else
        system(sprintf('open "%s"', pdf_path));
    end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TrialExplorerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {751, 751};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {412, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TrialExplorerUIFigure = uifigure('Visible', 'off');
            colormap(app.TrialExplorerUIFigure, 'cool');
            app.TrialExplorerUIFigure.Name = 'Trial Explorer';
            app.TrialExplorerUIFigure.Icon = fullfile(pathToMLAPP, 'TrialExplorer_icon.png');
            app.TrialExplorerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TrialExplorerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StructureTreeView = uitree(app.LeftPanel);
            app.StructureTreeView.SelectionChangedFcn = createCallbackFcn(app, @StructureTreeViewSelectionChanged, true);
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Log';
            app.SelectLogDropDownLabel = uilabel(app.LeftPanel);
            app.SelectLogDropDownLabel.Text = 'Select Log';
            app.ORLabel = uilabel(app.LeftPanel);
            app.ORLabel.Text = 'OR';
            app.MatFileDropdown = uidropdown(app.LeftPanel);
            app.MatFileDropdown.Items = {'---Select Log---'};
            app.MatFileDropdown.ValueChangedFcn = createCallbackFcn(app, @MatFileDropdownValueChanged, true);
            app.MatFileDropdown.Value = '---Select Log---';
            app.FullFiguresPDFReportButton = uibutton(app.LeftPanel, 'push');
            app.FullFiguresPDFReportButton.ButtonPushedFcn = createCallbackFcn(app, @FullFiguresPDFReportButtonPushed, true);
            app.FullFiguresPDFReportButton.Text = 'Full Figures PDF Report';
            app.RightPanel = uipanel(app.GridLayout);
            app.DataAxes = uiaxes(app.RightPanel);
            title(app.DataAxes, 'Select a Time Series ')
            xlabel(app.DataAxes, 'X')
            ylabel(app.DataAxes, 'Y')
            zlabel(app.DataAxes, 'Z')
            colormap(app.DataAxes, 'cool')
            app.DataAxes.Visible = 'off';
            app.ValueLabel = uilabel(app.RightPanel);
            app.ValueLabel.Text = '';
            app.NameLabel = uilabel(app.RightPanel);
            app.NameLabel.Text = '';
            app.DateLabel = uilabel(app.RightPanel);
            app.DateLabel.Text = '';
            app.DataTable = uitable(app.RightPanel);
            app.DataTable.ColumnName = '';
            app.DataTable.RowName = {};
            app.DataTable.Visible = 'off';
            % Show the figure after all components are created
            app.TrialExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrialExplorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrialExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrialExplorerUIFigure)
        end
    end
end