classdef loggerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        BornYearSpinner               matlab.ui.control.Spinner
        BornYearSpinnerLabel          matlab.ui.control.Label
        CaffeinLevelDropDown          matlab.ui.control.DropDown
        CaffeinLevelDropDownLabel     matlab.ui.control.Label
        DescriptionTextArea           matlab.ui.control.TextArea
        DescriptionTextAreaLabel      matlab.ui.control.Label
        SelfAlertnessRatingEditField  matlab.ui.control.NumericEditField
        SelfAlertnessRatingEditFieldLabel  matlab.ui.control.Label
        HoursSincemainSleepSpinner    matlab.ui.control.Spinner
        HoursSincemainSleepSpinnerLabel  matlab.ui.control.Label
        FlightTitleDropDown           matlab.ui.control.DropDown
        FlightTitleDropDownLabel      matlab.ui.control.Label
        TaskInfoLabel                 matlab.ui.control.Label
        PilotInfoLabel                matlab.ui.control.Label
        LegNumberSpinner              matlab.ui.control.Spinner
        LegNumberSpinnerLabel         matlab.ui.control.Label
        EndPVTEditField               matlab.ui.control.NumericEditField
        EndPVTEditFieldLabel          matlab.ui.control.Label
        BeginingPVTEditField          matlab.ui.control.NumericEditField
        BeginingPVTEditFieldLabel     matlab.ui.control.Label
        PilotRatingDropDown           matlab.ui.control.DropDown
        PilotRatingDropDownLabel      matlab.ui.control.Label
        PilotNameEditField            matlab.ui.control.EditField
        PilotNameEditFieldLabel       matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        FlightDataTab                 matlab.ui.container.Tab
        dmeDigit                      matlab.ui.control.Label
        rpmDigit                      matlab.ui.control.Label
        movingMap                     matlab.ui.container.Panel
        GSGauge                       matlab.ui.control.LinearGauge
        GSGaugeLabel                  matlab.ui.control.Label
        LOCCDIGauge                   matlab.ui.control.LinearGauge
        LOCCDIGaugeLabel              matlab.ui.control.Label
        RPMIndicator                  Aero.ui.control.RPMIndicator
        RPMIndicatorLabel             matlab.ui.control.Label
        HeadingIndicator              Aero.ui.control.HeadingIndicator
        HeadingIndicatorLabel         matlab.ui.control.Label
        ClimbIndicator                Aero.ui.control.ClimbIndicator
        ClimbIndicatorLabel           matlab.ui.control.Label
        ArtificialHorizon             Aero.ui.control.ArtificialHorizon
        ArtificialHorizonLabel        matlab.ui.control.Label
        Altimeter                     Aero.ui.control.Altimeter
        AltimeterLabel                matlab.ui.control.Label
        AirspeedIndicator             Aero.ui.control.AirspeedIndicator
        AirspeedIndicatorLabel        matlab.ui.control.Label
        EyeStateTab                   matlab.ui.container.Tab
        irisPosRel                    matlab.ui.control.UIAxes
        irisPos                       matlab.ui.control.UIAxes
        PilotInputTab                 matlab.ui.container.Tab
        ThrottleInputGauge            matlab.ui.control.LinearGauge
        ThrottleInputGaugeLabel       matlab.ui.control.Label
        RudderInputGauge              matlab.ui.control.LinearGauge
        RudderInputGaugeLabel         matlab.ui.control.Label
        pilotInput                    matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        TerminateTestButton           matlab.ui.control.Button
        recLamp                       matlab.ui.control.Lamp
        StartCameraButton             matlab.ui.control.Button
        SaveLogButton                 matlab.ui.control.Button
        RewindButton                  matlab.ui.control.Button
        RecordDataSwitch              matlab.ui.control.Switch
        RecordDataSwitchLabel         matlab.ui.control.Label
        SimLamp                       matlab.ui.control.Lamp
        EyeTrackerLamp                matlab.ui.control.Lamp
        ConnectSimulatorButton        matlab.ui.control.Button
        ConnectEyeTrackerButton       matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        pythonSocket % Description
        table % Description
        lastID = 0;
        irisx=0 % Description
        irisy=0 % Description
        dxIris=0 % Description
        dyIris=0 % Description
        rateGain=10 % Description
        devGain=1;
        xplaneSocket % Description
        dme = 0;
        fltTime = 0;
        hdev = 0;
        vdev = 0;
        elevator= 0;
        aileron = 0;
        throttle= 0;
        rudder=0;
        roll=0;
        pitch=0;
        heading=0;
        airspeed=0;
        altitude=0;
        rpm=0;
        dev=0;
        ddev=0;
        exceedance=0;
        recordTimer;
        updateUITimer;
        latitude=0;
        longitude=0;
        getDataLoopTimer;
        gx;
        vvi=0;
        irisEn =false;
        simEn = false;
    end
    
    methods (Access = public)
        
        function recordFun(app, ~, event)
            %do the recoring here
            app.lastID = app.lastID +1;
            app.table = [app.table;{app.lastID,app.fltTime,app.irisx,app.irisy,app.dxIris,app.dyIris,...
                app.aileron,app.elevator,app.throttle,app.rudder,app.roll,app.pitch,app.hdev,app.vdev,app.dme,...
                app.airspeed,app.altitude,app.vvi,app.heading,app.rpm,app.latitude,app.longitude}];
        end
        
        function initTable(app)
            Headers = {'id','time','irisx','irisy','dxIris','dyIris','aileron','elevator','throttle','rudder'...
                'roll','pitch','hdev','vdev','dmeNM','ias','altitude','vvi','heading','rpm','latitude','longitude'};
            app.table = [];
            app.table = cell2table(cell(0,length(Headers)),'VariableNames', Headers);
            app.lastID = 0;
        end
        
        function updateUI(app, ~, event)
             try
                 %disp(app.heading)
                  if app.irisEn
                    plot(app.irisPos, app.irisx , app.irisy, '.b','MarkerSize',15);
                    plot(app.irisPosRel, app.dxIris , app.dyIris, '.b','MarkerSize',15);
                  end

            if app.simEn
              plot(app.pilotInput, app.aileron , app.elevator, '.b','MarkerSize',15);
              app.AirspeedIndicator.Value = app.airspeed;
              app.ArtificialHorizon.Pitch = app.pitch;
              app.ArtificialHorizon.Roll = app.roll;
              app.ClimbIndicator.ClimbRate=app.vvi;
              app.Altimeter.Value = app.altitude;
              app.HeadingIndicator.Heading = app.heading;
               app.rpmDigit.Text = string(round(app.rpm,0));
               app.RPMIndicator.RPM = app.rpm*100/3000;
              app.LOCCDIGauge.Value = app.hdev;
              app.GSGauge.Value = app.vdev;
              app.RudderInputGauge.Value = app.rudder;
              app.ThrottleInputGauge.Value = app.throttle;
              app.dmeDigit.Text = string(round(app.dme,2))+" N.M";
            end

%           geoplot(app.gx,single(app.latitude),single(app.longitutde),'g-*');
%           geoplot(app.gx,single(30),single(40),'g-*');

%disp(app);

            catch xex
                 disp(getReport(xex))
            end

        end


        function getIrisData(app,~,event)
            try
                data = read(app.pythonSocket,4,"int32");% Receive a single UDP packet
                app.irisx = data(1);
                app.irisy = data(2);
                app.dxIris = data(3);
                app.dyIris = data(4);
                app.EyeTrackerLamp.Color = 'green';
%                  if app.pythonSocket.NumBytesAvailable >10
%                   app.pythonSocket.flush();
%                  end
                catch pex
                app.EyeTrackerLamp.Color = 'red';
%                 app.irisx = 0;
%                 app.irisy = 0;
%                 app.dxIris=0;
%                 app.dyIris=0;
%                 app.EyeTrackerLamp.Color = 'red';
%                 disp("Bad Iris Data!");
            end
            
        end
        
        function getDataLoop(app,~,event)
            if app.simEn
                app.getSimData();
            end

              if app.irisEn
                app.getIrisData();
              end


%                 app.dev = sqrt(app.hdev^2+app.vdev^2);
%                 app.ddev = gradient(app.dev);
%                 app.exceedance = app.rateGain*app.ddev + app.devGain*app.dev;
                %app.table = [app.table;{fltTime,centerx,centery,dx,dy,ail,elev,thr,hdev,vdev,dme}];   
        end
        
        function stopTasks(app)
           % Delete all timers from memory.
            listOfTimers = timerfindall
            if ~isempty(listOfTimers)
                delete(listOfTimers(:));
            end
            
            if ismethod(app.pythonSocket,'close')
                app.pythonSocket.close();
                disp("Python Port Closed!");
            end

            
              if app.simEn
               closeUDP(app.xplaneSocket);
               disp("Xplane Port Closed!")
             end
           app.SimLamp.Color = 'red';
           app.EyeTrackerLamp.Color = 'red';
        end
        
        function getSimData(app)
                try 
                    result = getDREFs({'sim/cockpit/radios/nav1_hdef_dot'...
                        ,'sim/cockpit/radios/nav1_vdef_dot' ...
                        ,'sim/cockpit2/radios/indicators/nav1_dme_distance_nm'...
                        ,'sim/cockpit2/controls/total_pitch_ratio'...
                        ,'sim/cockpit2/controls/total_roll_ratio'...
                        ,'sim/cockpit2/controls/total_heading_ratio'...
                        ,'sim/flightmodel/engine/ENGN_thro_override'...
                        ,'sim/time/total_flight_time_sec'...
                        ,'sim/cockpit2/engine/indicators/engine_speed_rpm'...
                        ,'sim/flightmodel/position/latitude'...
                        ,'sim/flightmodel/position/longitude'...
                        ,'sim/cockpit2/gauges/indicators/altitude_ft_pilot'...
                        ,'sim/flightmodel2/position/mag_psi'...
                        ,'sim/cockpit2/gauges/indicators/airspeed_kts_pilot'...
                        ,'sim/cockpit2/gauges/indicators/roll_vacuum_deg_pilot'...
                        ,'sim/cockpit2/gauges/indicators/pitch_vacuum_deg_pilot'...
                        ,'sim/cockpit2/gauges/indicators/vvi_fpm_pilot'
                        }, app.xplaneSocket);
                     app.airspeed = single(cell2mat(result(14)));
                     app.roll = single(cell2mat(result(15)));
                     app.pitch = single(cell2mat(result(16)));
                     app.vvi = single(cell2mat(result(17)));
                     app.dme = single(cell2mat(result(3)));
                     app.hdev = single(cell2mat(result(1)));
                     app.vdev = -1*single(cell2mat(result(2)));
                     app.elevator= single(cell2mat(result(4)));
                     app.aileron = single(cell2mat(result(5)));
                     app.rudder = single(cell2mat(result(6)));
                     app.throttle = single(cell2mat(result(7)));
                     app.fltTime = double(cell2mat(result(8)));
                     rpmArr = single(cell2mat(result(9)));
                     app.rpm = single(max(rpmArr)); 
                     app.latitude = single(cell2mat(result(10)));
                     app.longitude = single(cell2mat(result(11)));
                     app.altitude = single(cell2mat(result(12)));
                     app.heading = single(cell2mat(result(13)));
                     app.SimLamp.Color = 'green';
                     %irisPos = sqrt(centerx^2 + centery^2);
                 catch xex
                     disp(getReport(xex))
%                      app.dev = single(0) ;
%                      app.hdev = single(0);
%                      app.vdev = single(0);
%                      app.elevator=single(0);
%                      app.aileron = single(0);
%                      app.throttle = single(0);
%                      app.fltTime = single(0); 
%                      app.dme=single(0);
                     app.SimLamp.Color = 'red';
                     %disp("No X-Plane Connect");
                     pause(1)
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %addpath('../')
            lat= 35;
            long= 51; 
            app.gx = geoaxes(app.movingMap);
            app.gx.Basemap = 'darkwater';
            app.gx.ZoomLevel = 5;
            geoplot(app.gx,lat,long,'g-*');


%% Setup
% Create variables and open connection to X-Plane
app.initTable();


%plot
yLL = 0;
yUL = +1000;
app.irisPos.YLim = [yLL yUL];
app.irisPos.YTick = round(linspace(yLL, yUL, 5), 2, 'significant');

xLL = 0;
xUL = 1000;
app.irisPos.XLim = [xLL xUL];
app.irisPos.XTick = round(linspace(xLL, xUL, 5), 2, 'significant');

yLL2 = -50;
yUL2 = +50;
app.irisPosRel.YLim = [yLL2 yUL2];
app.irisPosRel.YTick = round(linspace(yLL2, yUL2, 5), 2, 'significant');

xLL2 = 0;
xUL2 = 300;
app.irisPosRel.XLim = [xLL2 xUL2];
app.irisPosRel.XTick = round(linspace(xLL2, xUL2, 5), 2, 'significant');


%creat Timer
% Create timer object
            app.recordTimer = timer(...
            'ExecutionMode', 'fixedRate', ...      % Run timer repeatedly
            'Period', 0.01, ...                       % Period is 5 seconds
            'BusyMode', 'queue',...                % Queue timer callbacks when busy
            'TimerFcn', @app.recordFun);   % Callback that runs every period
            app.updateUITimer = timer(...
            'ExecutionMode', 'fixedRate', ...      % Run timer repeatedly
            'Period', 0.1, ...                       % Period is 5 seconds
            'BusyMode', 'queue',...                % Queue timer callbacks when busy
            'TimerFcn', @app.updateUI);   % Callback that runs every period
            app.getDataLoopTimer = timer(...
            'ExecutionMode', 'fixedDelay', ...      % Run timer repeatedly
            'Period', 0.01, ...                       % Period is 5 seconds
            'BusyMode', 'queue',...                % Queue timer callbacks when busy
            'TimerFcn', @app.getDataLoop);   % Callback that runs every period


        end

        % Button pushed function: SaveLogButton
        function SaveLogButtonPushed(app, event)
            [C,ia] = unique(app.table(:,1),'rows');
            app.table = app.table(ia,:);
            %cur Time
             pilot.name = app.PilotNameEditField.Value;
             pilot.rating = app.PilotRatingDropDown.Value;
             pilot.beginPVT = app.BeginingPVTEditField.Value;
             pilot.endPVT = app.EndPVTEditField.Value;
             pilot.hoursAwake = app.HoursSincemainSleepSpinner.Value;
             pilot.selfRating = app.SelfAlertnessRatingEditField.Value;
             pilot.taskTitle = app.FlightTitleDropDown.Value;
             pilot.data = app.table;
             pilot.description = app.DescriptionTextArea.Value;
             pilot.time = string(datetime("now"));
             pilot.caffein = app.CaffeinLevelDropDown.Value;
             fileName = datestr(now, 'dd-mm-yyyy_HH-MM-SS');
             fileName = pilot.name+"_"+fileName;
             folderName = 'logs/'+fileName;
             mkdir(folderName);
             logFile = folderName+'/'+fileName;
            save(logFile,'pilot') ;
            msgbox(["Log Successfully Saved!",logFile],logFile);
            disp('--End of example program--');
        end

        % Button pushed function: ConnectEyeTrackerButton
        function ConnectEyeTrackerButtonPushed(app, event)
        try
            app.irisEn = true;
            app.EyeTrackerLamp.Color = "green";
            app.pythonSocket = udpport("LocalHost","127.0.0.1","LocalPort",7070);
                if strcmp(app.getDataLoopTimer.Running, 'off') 
                    start(app.getDataLoopTimer);
                end    
                if strcmp(app.updateUITimer.Running, 'off') 
                    start(app.updateUITimer);
                end
            
        catch
            app.irisEn = false;
            app.EyeTrackerLamp.Color = "red";
        end

                
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            stopTasks(app);
            delete(app)


        end

        % Button pushed function: ConnectSimulatorButton
        function ConnectSimulatorButtonPushed(app, event)
            try
                app.xplaneSocket = openUDP();
                app.SimLamp.Color="green";
                app.simEn = true;
                if strcmp(app.getDataLoopTimer.Running, 'off') 
                    start(app.getDataLoopTimer);
                end
                if strcmp(app.updateUITimer.Running, 'off') 
                    start(app.updateUITimer);
                end
                
            catch
%                 disp("No X-Plane!");
                  app.SimLamp.Color="red";
                  app.simEn = false;
                  pause(1);
            end
            
        end

        % Value changed function: RecordDataSwitch
        function RecordDataSwitchValueChanged(app, event)
            record = app.RecordDataSwitch.Value;
            disp(record);
            if strcmp(record,'On')
               % Start timer
               if strcmp(app.recordTimer.Running, 'off') 
                   start(app.recordTimer);
                   app.recLamp.Color="green";
               end
            end
            if strcmp(record,'Off')
               % Start timer
               stop(app.recordTimer);
               app.recLamp.Color="red";
               disp("off Rec");
            end


        end

        % Button pushed function: RewindButton
        function RewindButtonPushed(app, event)
            app.initTable();
            app.lastID =0;
        end

        % Button pushed function: TerminateTestButton
        function TerminateTestButtonPushed(app, event)
            stopTasks(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {603, 603, 603};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {603, 603};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {250, '1x', 187};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PilotNameEditFieldLabel = uilabel(app.LeftPanel);
            app.PilotNameEditFieldLabel.Text = 'Pilot Name';
            app.PilotNameEditField = uieditfield(app.LeftPanel, 'text');
            app.PilotRatingDropDownLabel = uilabel(app.LeftPanel);
            app.PilotRatingDropDownLabel.Text = 'Pilot Rating';
            app.PilotRatingDropDown = uidropdown(app.LeftPanel);
            app.PilotRatingDropDown.Items = {'Student', 'FO below 1000 hrs', 'FO below 2000 hrs', 'FO above 2000 hrs', 'Captain', 'Flight Instructor'};
            app.PilotRatingDropDown.Value = 'Student';
            app.BeginingPVTEditFieldLabel = uilabel(app.LeftPanel);
            app.BeginingPVTEditFieldLabel.Text = 'Begining PVT';
            app.BeginingPVTEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EndPVTEditFieldLabel = uilabel(app.LeftPanel);
            app.EndPVTEditFieldLabel.Text = 'End PVT';
            app.EndPVTEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LegNumberSpinnerLabel = uilabel(app.LeftPanel);
            app.LegNumberSpinnerLabel.Text = 'Leg Number';
            app.LegNumberSpinner = uispinner(app.LeftPanel);
            app.LegNumberSpinner.Limits = [0 Inf];
            app.LegNumberSpinner.Value = 1;
            app.PilotInfoLabel = uilabel(app.LeftPanel);
            app.PilotInfoLabel.Text = 'Pilot Info';
            app.TaskInfoLabel = uilabel(app.LeftPanel);
            app.TaskInfoLabel.Text = 'Task Info';
            app.FlightTitleDropDownLabel = uilabel(app.LeftPanel);
            app.FlightTitleDropDownLabel.Text = 'Flight Title';
            app.FlightTitleDropDown = uidropdown(app.LeftPanel);
            app.FlightTitleDropDown.Items = {'OIII ILS 29L/KAZ', 'OIII ILS 29L/PARIR'};
            app.FlightTitleDropDown.Value = 'OIII ILS 29L/KAZ';
            app.HoursSincemainSleepSpinnerLabel = uilabel(app.LeftPanel);
            app.HoursSincemainSleepSpinnerLabel.Text = 'Hours Since main Sleep';
            app.HoursSincemainSleepSpinner = uispinner(app.LeftPanel);
            app.HoursSincemainSleepSpinner.Limits = [0 Inf];
            app.HoursSincemainSleepSpinner.Value = 1;
            app.SelfAlertnessRatingEditFieldLabel = uilabel(app.LeftPanel);
            app.SelfAlertnessRatingEditFieldLabel.Text = 'Self Alertness Rating';
            app.SelfAlertnessRatingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SelfAlertnessRatingEditField.Limits = [0 100];
            app.DescriptionTextAreaLabel = uilabel(app.LeftPanel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.LeftPanel);
            app.CaffeinLevelDropDownLabel = uilabel(app.LeftPanel);
            app.CaffeinLevelDropDownLabel.Text = 'Caffein Level';
            app.CaffeinLevelDropDown = uidropdown(app.LeftPanel);
            app.CaffeinLevelDropDown.Items = {'None', 'Low', 'High'};
            app.CaffeinLevelDropDown.Value = 'None';
            app.BornYearSpinnerLabel = uilabel(app.LeftPanel);
            app.BornYearSpinnerLabel.Text = 'Born Year';
            app.BornYearSpinner = uispinner(app.LeftPanel);
            app.BornYearSpinner.Limits = [0 Inf];
            app.BornYearSpinner.Value = 1995;
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.FlightDataTab = uitab(app.TabGroup);
            app.FlightDataTab.Title = 'Flight Data';
            app.AirspeedIndicatorLabel = uilabel(app.FlightDataTab);
            app.AirspeedIndicatorLabel.Text = 'Airspeed Indicator';
            app.AirspeedIndicator = uiaeroairspeed(app.FlightDataTab);
            app.AirspeedIndicator.Limits = [40 200];
            app.AltimeterLabel = uilabel(app.FlightDataTab);
            app.AltimeterLabel.Text = 'Altimeter';
            app.Altimeter = uiaeroaltimeter(app.FlightDataTab);
            app.ArtificialHorizonLabel = uilabel(app.FlightDataTab);
            app.ArtificialHorizonLabel.Text = 'ArtificialHorizon';
            app.ArtificialHorizon = uiaerohorizon(app.FlightDataTab);
            app.ClimbIndicatorLabel = uilabel(app.FlightDataTab);
            app.ClimbIndicatorLabel.Text = 'ClimbIndicator';
            app.ClimbIndicator = uiaeroclimb(app.FlightDataTab);
            app.HeadingIndicatorLabel = uilabel(app.FlightDataTab);
            app.HeadingIndicatorLabel.Text = 'HeadingIndicator';
            app.HeadingIndicator = uiaeroheading(app.FlightDataTab);
            app.RPMIndicatorLabel = uilabel(app.FlightDataTab);
            app.RPMIndicatorLabel.Text = 'RPMIndicator';
            app.RPMIndicator = uiaerorpm(app.FlightDataTab);
            app.LOCCDIGaugeLabel = uilabel(app.FlightDataTab);
            app.LOCCDIGaugeLabel.Text = 'LOC/CDI';
            app.LOCCDIGauge = uigauge(app.FlightDataTab, 'linear');
            app.LOCCDIGauge.Limits = [-5 5];
            app.LOCCDIGauge.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.LOCCDIGauge.MajorTickLabels = {''};
            app.LOCCDIGauge.MinorTicks = [];
            app.GSGaugeLabel = uilabel(app.FlightDataTab);
            app.GSGaugeLabel.Text = 'G/S';
            app.GSGauge = uigauge(app.FlightDataTab, 'linear');
            app.GSGauge.Limits = [-5 5];
            app.GSGauge.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.GSGauge.MajorTickLabels = {''};
            app.GSGauge.MinorTicks = [];
            app.movingMap = uipanel(app.FlightDataTab);
            app.rpmDigit = uilabel(app.FlightDataTab);
            app.rpmDigit.Text = '0';
            app.dmeDigit = uilabel(app.FlightDataTab);
            app.dmeDigit.Text = '0';
            app.EyeStateTab = uitab(app.TabGroup);
            app.EyeStateTab.Title = 'Eye State';
            app.irisPos = uiaxes(app.EyeStateTab);
            title(app.irisPos, 'Iris Position Absolute')
            app.irisPosRel = uiaxes(app.EyeStateTab);
            title(app.irisPosRel, 'Iris Position Relative')
            app.PilotInputTab = uitab(app.TabGroup);
            app.PilotInputTab.Title = 'Pilot Input';
            app.pilotInput = uiaxes(app.PilotInputTab);
            title(app.pilotInput, 'PIlot Input Aileron/Elevator')
            app.pilotInput.XLim = [-1 1];
            app.pilotInput.YLim = [-1 1];
            app.RudderInputGaugeLabel = uilabel(app.PilotInputTab);
            app.RudderInputGaugeLabel.Text = 'Rudder Input';
            app.RudderInputGauge = uigauge(app.PilotInputTab, 'linear');
            app.RudderInputGauge.Limits = [-1 1];
            app.ThrottleInputGaugeLabel = uilabel(app.PilotInputTab);
            app.ThrottleInputGaugeLabel.Text = 'Throttle Input';
            app.ThrottleInputGauge = uigauge(app.PilotInputTab, 'linear');
            app.ThrottleInputGauge.Limits = [0 1];
            app.RightPanel = uipanel(app.GridLayout);
            app.ConnectEyeTrackerButton = uibutton(app.RightPanel, 'push');
            app.ConnectEyeTrackerButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectEyeTrackerButtonPushed, true);
            app.ConnectEyeTrackerButton.Text = ' Connect Eye Tracker';
            app.ConnectSimulatorButton = uibutton(app.RightPanel, 'push');
            app.ConnectSimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSimulatorButtonPushed, true);
            app.ConnectSimulatorButton.Text = 'Connect Simulator';
            app.EyeTrackerLamp = uilamp(app.RightPanel);
            app.SimLamp = uilamp(app.RightPanel);
            app.RecordDataSwitchLabel = uilabel(app.RightPanel);
            app.RecordDataSwitchLabel.Text = 'Record Data';
            app.RecordDataSwitch = uiswitch(app.RightPanel, 'slider');
            app.RecordDataSwitch.ItemsData = {'Off', 'On'};
            app.RecordDataSwitch.ValueChangedFcn = createCallbackFcn(app, @RecordDataSwitchValueChanged, true);
            app.RewindButton = uibutton(app.RightPanel, 'push');
            app.RewindButton.ButtonPushedFcn = createCallbackFcn(app, @RewindButtonPushed, true);
            app.RewindButton.Text = 'Rewind';
            app.SaveLogButton = uibutton(app.RightPanel, 'push');
            app.SaveLogButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLogButton.Text = 'Save Log';
            app.StartCameraButton = uibutton(app.RightPanel, 'push');
            app.StartCameraButton.Text = 'Start Camera';
            app.recLamp = uilamp(app.RightPanel);
            app.TerminateTestButton = uibutton(app.RightPanel, 'push');
            app.TerminateTestButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateTestButtonPushed, true);
            app.TerminateTestButton.Text = 'Terminate Test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loggerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end