classdef outputStructTreeView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ORLabel                 matlab.ui.control.Label
        ShowTimeCheckBox        matlab.ui.control.CheckBox
        DataTable               matlab.ui.control.Table
        MatFileDropdown         matlab.ui.control.DropDown
        SelectLogDropDownLabel  matlab.ui.control.Label
        DateLabel               matlab.ui.control.Label
        NameLabel               matlab.ui.control.Label
        LoadButton              matlab.ui.control.Button
        ValueLabel              matlab.ui.control.Label
        StructureTreeView       matlab.ui.container.Tree
        DataAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        my_struct; % Description
    end
    
    methods (Access = private)
        
    % Modify the display_struct function
function display_struct(app, parent, struct_obj)
    fields = fieldnames(struct_obj);
    
    for i = 1:numel(fields)
        field_name = fields{i};
        field_value = struct_obj.(field_name);
        field_type = class(field_value);
        
        if isstruct(field_value)
            node = uitreenode(parent, 'Text', [field_name ': (struct)']);
            display_struct(app, node, field_value);
        else
            node = uitreenode(parent, 'Text', [field_name ': (' field_type ')']);
            if isnumeric(field_value) || ischar(field_value)
                node.NodeData = field_value;
            end
        end
    end
end


        function plot_data(app, time, data, field_name)
    if iscell(data)
        cell_data = cell2mat(data);
        plot(app.DataAxes, time, cell_data);
    else
        plot(app.DataAxes, time, data);
    end
    
    grid(app.DataAxes, 'on');
    
    xlabel(app.DataAxes, 'Time');
    ylabel(app.DataAxes, field_name, 'Interpreter', 'none');
    title(app.DataAxes, sprintf('%s vs Time', strrep(field_name, '_', '-')), 'Interpreter', 'none');
        end
        
        % Private function to update the MatFileDropdown items
% Modify the update_dropdown_items function to include the disabled item
function update_dropdown_items(app)
    default_folder = 'outputLogs';
    mat_files = dir(fullfile(default_folder, '*.mat'));
    mat_file_names = {mat_files.name};
    
    app.MatFileDropdown.Items = ["--select a Log--", mat_file_names];
    app.MatFileDropdown.ItemsData = [0, 1:numel(mat_file_names)];
    app.MatFileDropdown.Value = 0;
end
    end
    
    
    methods (Access = public)
        
        function   updateUI(app,loaded_data)
            struct_name = fieldnames(loaded_data);
    app.my_struct = loaded_data.(struct_name{1});
    
    % Update NameLabel if the 'name' field exists in the struct
    if isfield(app.my_struct.trial, 'name')
        app.NameLabel.Text = sprintf('Name: %s', app.my_struct.trial.name);
    else
        app.NameLabel.Text = 'Name not found';
    end
    
    % Update DateLabel if the 'time' field exists in the struct
    if isfield(app.my_struct.trial, 'time')
        date_str = datestr(app.my_struct.trial.time, 'yyyy-mm-dd HH:MM:SS');
        app.DateLabel.Text = sprintf('Date: %s', date_str);
    else
        app.DateLabel.Text = 'Date not found';
    end
    
    % Clear the existing tree
    app.StructureTreeView.Children.delete;
    
    % Display the struct in the tree view
    display_struct(app, app.StructureTreeView, app.my_struct);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_dropdown_items(app);
    
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
          
   default_folder = 'outputLogs';
    [file, path] = uigetfile(fullfile(default_folder, '*.mat'), 'Select a .mat file');
    
    if isequal(file, 0)
        return;
    end
    
    loaded_data = load(fullfile(path, file));
    app.updateUI(loaded_data);

update_dropdown_items(app);
        end

        % Selection changed function: StructureTreeView
        function StructureTreeViewSelectionChanged(app, event)
selected_node = app.StructureTreeView.SelectedNodes;
    field_name = strsplit(selected_node.Text, ':');
    field_name = strtrim(field_name{1});
    
    if isempty(selected_node.NodeData)
        app.ValueLabel.Text = '';
        app.DataTable.Visible = 'off';
    elseif isnumeric(selected_node.NodeData) && isscalar(selected_node.NodeData)
        app.ValueLabel.Text = sprintf('%s: %g', field_name, selected_node.NodeData);
        app.DataTable.Visible = 'off';
    elseif ischar(selected_node.NodeData)
        app.ValueLabel.Text = sprintf('%s: %s', field_name, selected_node.NodeData);
        app.DataTable.Visible = 'off';
    else
        data = selected_node.NodeData;
        if (isvector(data) || iscell(data)) && ...
                isfield(app.my_struct, 'resampledTrial') && isfield(app.my_struct.resampledTrial, 'data') && ...
                isfield(app.my_struct.resampledTrial.data, 'time')

            time = app.my_struct.resampledTrial.data.time;

            if numel(time) == numel(data)
                plot_data(app, time, data, field_name);
            else
                app.ValueLabel.Text = sprintf('%s: Data and time dimensions do not match', field_name);
            end
        end
         if iscell(data) || ismatrix(data)
            app.DataTable.ColumnName = {'Data'};
            app.DataTable.Data = data;
            app.DataTable.Visible = 'on';
        else
            app.DataTable.Visible = 'off';
        end
    end
         
        end

        % Value changed function: MatFileDropdown
        function MatFileDropdownValueChanged(app, event)
            % Callback function for the MatFileDropdown component
    if app.MatFileDropdown.Value == 0
        return;
    end
     default_folder = 'outputLogs';
selected_file_index = app.MatFileDropdown.Value+1;
    selected_file = app.MatFileDropdown.Items{selected_file_index};    
    loaded_data = load(fullfile(default_folder, selected_file));
    app.updateUI(loaded_data);


        end

        % Value changed function: ShowTimeCheckBox
        function ShowTimeCheckBoxValueChanged(app, event)
         if app.ShowTimeCheckBox.Value
            if isfield(app.my_struct, 'resampledTrial') && isfield(app.my_struct.resampledTrial, 'data') && ...
                    isfield(app.my_struct.resampledTrial.data, 'time')
                time_data = app.my_struct.resampledTrial.data.time;
                app.DataTable.ColumnName = {'Time', 'Data'};
                app.DataTable.Data = [time_data, app.DataTable.Data];
            else
                app.ShowTimeCheckBox.Value = false;
                uialert(app.UIFigure, 'Time data is not available in the struct.', 'Error');
            end
             else
                             app.DataTable.ColumnName = {'Data'};
            app.DataTable.Data = app.DataTable.Data(:, 2:end);
        end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataAxes = uiaxes(app.UIFigure);
            title(app.DataAxes, 'Title')
            xlabel(app.DataAxes, 'X')
            ylabel(app.DataAxes, 'Y')
            zlabel(app.DataAxes, 'Z')
            app.StructureTreeView = uitree(app.UIFigure);
            app.StructureTreeView.SelectionChangedFcn = createCallbackFcn(app, @StructureTreeViewSelectionChanged, true);
            app.ValueLabel = uilabel(app.UIFigure);
            app.ValueLabel.Text = '';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Log';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = '';
            app.DateLabel = uilabel(app.UIFigure);
            app.DateLabel.Text = '';
            app.SelectLogDropDownLabel = uilabel(app.UIFigure);
            app.SelectLogDropDownLabel.Text = 'Select Log';
            app.MatFileDropdown = uidropdown(app.UIFigure);
            app.MatFileDropdown.Items = {'---Select Log---'};
            app.MatFileDropdown.ValueChangedFcn = createCallbackFcn(app, @MatFileDropdownValueChanged, true);
            app.MatFileDropdown.Value = '---Select Log---';
            app.DataTable = uitable(app.UIFigure);
            app.DataTable.ColumnName = '';
            app.DataTable.RowName = {};
            app.DataTable.Visible = 'off';
            app.ShowTimeCheckBox = uicheckbox(app.UIFigure);
            app.ShowTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTimeCheckBoxValueChanged, true);
            app.ShowTimeCheckBox.Visible = 'off';
            app.ShowTimeCheckBox.Text = 'Show Time';
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = outputStructTreeView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end